classdef test1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ListBoxLabel       matlab.ui.control.Label
        ListBox            matlab.ui.control.ListBox
        initButton         matlab.ui.control.Button
        calc_totalButton   matlab.ui.control.Button
        calc_sensorButton  matlab.ui.control.Button
        disp_totalButton   matlab.ui.control.Button
        disp_sensorButton  matlab.ui.control.Button
        workspaceButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        init %initmeuk
        R_Sensors %results of calc_sensors
        list %geeft lbox
    end
    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Callback function: ListBox, initButton
        function initButtonPushed(app, event)
            [T_alive, E_available, Sensor, Sensorname, MCU, MCUname, Transmission, Transmissionname] = init_main();
            app.init.T_alive = T_alive;
            app.init.E_available = E_available;
            app.init.Sensor = Sensor;
            app.init.Sensorname = Sensorname;
            app.init.MCU = MCU;
            app.init.MCUname = MCUname;
            app.init.Transmission = Transmission;
            app.init.Transmissionname = Transmissionname;
            app.ListBox.Items=app.init.Sensorname(1,:);
            
            test = 0;
            if test == 0
                test1 = 0;
            end
            test = 1;
            test1 = test1 +1;
        end

        % Button pushed function: calc_sensorButton
        function calc_sensorButtonPushed(app, event)
            N_Max = 0;
            Selected_Sensors = app.init.Sensor;
            [Results_calc_sensors, P_S_Measure, P_s_SHDN, E_s_consumed] = calc_sensors(Selected_Sensors, N_Max);
            app.R_Sensors.R =Results_calc_sensors;
            app.R_Sensors.P_S_Measure = P_S_Measure;
        end

        % Button pushed function: calc_totalButton
        function calc_totalButtonPushed(app, event)
            S_Sensors = [Sensor(:,1) Sensor(:,3)];
            S_MCU = MCU(:,1);
            S_Com = Transmission(:,1);
            S_names = [Sensorname(1,1) Sensorname(1,3) MCUname(1,1) Transmissionname(1,1) "Total"];
            I_Array = [ (3600) 1 0 1 0; (3*3600) 1 1 1 1];
            T_Max = 3*24*3600;
            E_Max = 30000;
            
            [P_Sub, E_Sub, measurements2, time,tmeasurement, tltest] = calc_totalenergy(S_Sensors, S_MCU, S_Com, I_Array, T_Max, E_Max);
        end

        % Button pushed function: disp_totalButton
        function disp_totalButtonPushed(app, event)
            disp_totalenergy(P_Sub, E_Sub, time, tmeasurement, S_names);
        end

        % Button pushed function: disp_sensorButton
        function disp_sensorButtonPushed(app, event)
            disp_sensors(app.R_Sensors.R, app.init.Sensorname);
        end

        % Callback function
        function ListBoxValueChanged(app, event)

        end

        % Button pushed function: workspaceButton
        function workspaceButtonPushed(app, event)
            app.list = app.ListBox.Value;
            test = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @initButtonPushed, true);
            app.ListBox.Value = {};
            app.initButton = uibutton(app.UIFigure, 'push');
            app.initButton.ButtonPushedFcn = createCallbackFcn(app, @initButtonPushed, true);
            app.initButton.Text = 'init';
            app.calc_totalButton = uibutton(app.UIFigure, 'push');
            app.calc_totalButton.ButtonPushedFcn = createCallbackFcn(app, @calc_totalButtonPushed, true);
            app.calc_totalButton.Text = 'calc_total';
            app.calc_sensorButton = uibutton(app.UIFigure, 'push');
            app.calc_sensorButton.ButtonPushedFcn = createCallbackFcn(app, @calc_sensorButtonPushed, true);
            app.calc_sensorButton.Text = 'calc_sensor';
            app.disp_totalButton = uibutton(app.UIFigure, 'push');
            app.disp_totalButton.ButtonPushedFcn = createCallbackFcn(app, @disp_totalButtonPushed, true);
            app.disp_totalButton.Text = 'disp_total';
            app.disp_sensorButton = uibutton(app.UIFigure, 'push');
            app.disp_sensorButton.ButtonPushedFcn = createCallbackFcn(app, @disp_sensorButtonPushed, true);
            app.disp_sensorButton.Text = 'disp_sensor';
            app.workspaceButton = uibutton(app.UIFigure, 'push');
            app.workspaceButton.ButtonPushedFcn = createCallbackFcn(app, @workspaceButtonPushed, true);
            app.workspaceButton.Text = 'workspace';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end